classdef HMLET < matlab.apps.AppBase
    %HMLET Handy Machine Learning Eye-tracking Toolbox
    %   Ver 2.00 Jan 2024

    % Properties that correspond to app components
    properties (Access = public)
        HMLETUIFigure            matlab.ui.Figure
        InfoMenu                 matlab.ui.container.Menu
        testImportButton         matlab.ui.control.Button
        StatusBar                matlab.ui.control.EditField
        LoadEncodingDataButton   matlab.ui.control.Button
        LoadRetrievalDataButton  matlab.ui.control.Button
        EMSAnalysisButton        matlab.ui.control.Button
        FeatureAnalysisButton    matlab.ui.control.Button
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        HMLETversion;
        versionInfo = 'Ver. 2.00 Jan 2024'; % Description
    end
    %----------------------------------------- Eyetracking Variables
    properties (Access = private)
        dat; % Eyetracking Data Structure
        dataPath; % Eyetracking Data Directory
    end
    
    methods (Access = private)
        function InitilizingVars(app)
            app.HMLETversion = string(app.versionInfo(6:9));
            app.versionInfo = string(app.versionInfo);
            app.dat.encoding = table;
            app.dat.retrieval = table;
            app.dataPath = string([pwd,filesep]);

            app.StatusBar.Value = "Welcome to HMLET V."+app.HMLETversion;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitilizingVars(app)
            movegui(app.HMLETUIFigure, 'center');
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            msgbox({"HMLET";"Handy Machine Learning Eye-tracking Toolbox";app.versionInfo},"HMLET");
        end

        % Button pushed function: LoadRetrievalDataButton
        function LoadRetrievalDataButtonPushed(app, event)
            app.StatusBar.Value = "Loading Retrieval Data...";
            userInput = questdlg("Please select the Retrieval csv file",...
                                 "Retrieval","Browse","Cancel","Browse");
            if(userInput == "Browse")
                 [fileName, path] = uigetfile(app.dataPath + "*.csv","Retrieval");
                 if(ischar(fileName))
                    app.dataPath = string(path);
                    app.dat.retrieval = funcs.LoadData(app.dataPath,string(fileName));
                    
                    app.LoadRetrievalDataButton.BackgroundColor = [0.57, 0.98, 0.41];
                    app.LoadEncodingDataButton.Enable = "on";
                    app.FeatureAnalysisButton.Enable = "on";
                    app.StatusBar.Value = "Retrieval data is loaded.";
                 else
                     app.StatusBar.Value = "Canceled.";
                 end
            else
                app.StatusBar.Value = "Canceled.";
            end
            
        end

        % Button pushed function: LoadEncodingDataButton
        function LoadEncodingDataButtonPushed(app, event)
            userInput = questdlg("Please select the Encoding csv file",...
                                 "Encoding","Browse","Cancel","Browse");
            if(userInput == "Browse")
                [fileName, path] = uigetfile(app.dataPath + "*.csv","Encoding");
                if(ischar(fileName))
                    app.dataPath = string(path);
                    app.dat.encoding = funcs.LoadData(app.dataPath,string(fileName));

                    app.dat.mapEncRet = app.dat.encoding(:,["ID","RetrievalDPID"]);
                    app.dat.mapEncRet.EncodingDPID = datEncoding.DataPointID;

                    app.LoadEncodingDataButton.BackgroundColor = [0.57, 0.98, 0.41];
                    app.EMSAnalysisButton.Enable = "on";
                    app.StatusBar.Value = "Encoding data is loaded.";
                else
                    app.StatusBar.Value = "Canceled.";
                end
            else
                app.StatusBar.Value = "Canceled.";
            end
        end

        % Button pushed function: testImportButton
        function testImportButtonPushed(app, event)
            comp = DataViewer;
            comp.Data = app.dat.retrieval;
            % while(comp.Visible)
            % end
            a=0;
            % app.DataViewer.Data = app.dat.retrieval;
            % app.DataViewer.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HMLETUIFigure = uifigure('Visible', 'off');
            app.HMLETUIFigure.Name = 'HMLET';
            app.HMLETUIFigure.Icon = fullfile(pathToMLAPP, 'Media', 'Logo.jpg');
            app.InfoMenu = uimenu(app.HMLETUIFigure);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.InfoMenu.Tag = 'About HMLET';
            app.Image = uiimage(app.HMLETUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Media', 'HMLET.jpg');
            app.FeatureAnalysisButton = uibutton(app.HMLETUIFigure, 'push');
            app.FeatureAnalysisButton.Enable = 'off';
            app.FeatureAnalysisButton.Tooltip = {'Feature Analysis'};
            app.FeatureAnalysisButton.Text = 'Feature Analysis';
            app.EMSAnalysisButton = uibutton(app.HMLETUIFigure, 'push');
            app.EMSAnalysisButton.Enable = 'off';
            app.EMSAnalysisButton.Tooltip = {'Exploration-Map Similarity Analysis'};
            app.EMSAnalysisButton.Text = 'EMS Analysis';
            app.LoadRetrievalDataButton = uibutton(app.HMLETUIFigure, 'push');
            app.LoadRetrievalDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRetrievalDataButtonPushed, true);
            app.LoadRetrievalDataButton.Tooltip = {'Click to load data'};
            app.LoadRetrievalDataButton.Text = 'Load Retrieval Data';
            app.LoadEncodingDataButton = uibutton(app.HMLETUIFigure, 'push');
            app.LoadEncodingDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEncodingDataButtonPushed, true);
            app.LoadEncodingDataButton.Enable = 'off';
            app.LoadEncodingDataButton.Tooltip = {'Click to load data'};
            app.LoadEncodingDataButton.Text = 'Load Encoding Data';
            app.StatusBar = uieditfield(app.HMLETUIFigure, 'text');
            app.StatusBar.Tooltip = {'Statud Bar'};
            app.StatusBar.Value = 'Initializing...';
            app.testImportButton = uibutton(app.HMLETUIFigure, 'push');
            app.testImportButton.ButtonPushedFcn = createCallbackFcn(app, @testImportButtonPushed, true);
            app.testImportButton.Text = 'test Import';
            % Show the figure after all components are created
            app.HMLETUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HMLET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HMLETUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HMLETUIFigure)
        end
    end
end